classdef UI_Blockchain < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        BlockChainTab            matlab.ui.container.Tab
        BlockChainSimulationAnirudhsNodeLabel  matlab.ui.control.Label
        DataEditFieldLabel       matlab.ui.control.Label
        DataEditField            matlab.ui.control.EditField
        MineButton               matlab.ui.control.Button
        UITable                  matlab.ui.control.Table
        MinerateEditField        matlab.ui.control.EditField
        StartButton              matlab.ui.control.Button
        CENLabel                 matlab.ui.control.Label
        StopButton               matlab.ui.control.Button
        DataEditFieldLabel_2     matlab.ui.control.Label
        PeersTab                 matlab.ui.container.Tab
        EditField_2              matlab.ui.control.EditField
        AddremoteEndpointButton  matlab.ui.control.Button
        CENLabel_2               matlab.ui.control.Label
        EditField_3              matlab.ui.control.EditField
        JoinNetworkButton        matlab.ui.control.Button
        AIE24Label               matlab.ui.control.Label
    end

    
    properties (Access = private)
        blockchain % Description


        peerupdatetimer % Description
        chainupdatetimer % Description
        remotepeerendpoint % Description
    end
    properties (Access= public)
        peers
        opts
        peerlistendpoint
        
    end
    
    methods (Static)
        
        function APP =   updatePeers(~,~,app)
            
            peerstruct = webread(app.peerlistendpoint);
            
            app.peers=[];
            for i=1:length(peerstruct)
                app.peers = [app.peers convertCharsToStrings(peerstruct(i).Peers)];
            end
            
            disp(app.peers)
            APP = app;
            
            
        end
        function APP = updateChain(~,~,app)
            disp("chain update try")
            remotechainstr = webread(app.remotepeerendpoint );
            remotechain=[];
            if length(remotechainstr)>1
                
                  for i=1:length(remotechainstr)
                
               
                remotechain = [remotechain Block((remotechainstr(i).data),remotechainstr(i).previous_hash, ...
    str2num(remotechainstr(i).nonce),str2num(remotechainstr(i).difficulty), remotechainstr(i).hash, datetime(remotechainstr(i).timestamp,'Format','HH:mm:ss') )];
            
                  end
            disp(remotechain(end).hash)
             disp(Blockchain.isValidChain(remotechain));
            
            if ( convertCharsToStrings(remotechain(end).hash) ~= convertCharsToStrings( app.blockchain.chain(end).hash) )
               
                
                if Blockchain.isValidChain(remotechain)
                    app.blockchain.chain = remotechain;
                    app.UpdateUITable();
                end
                
                
            end
                
            end
          
            
            APP=app;
        end
    end
    
    methods (Access = private)
        
        function results = write2peers(app,minedblock)
             
                minedblockstruct = struct(minedblock);
            for i=1:length(app.peers)
                webwrite(app.peers(i),{minedblockstruct},app.opts);
            end
            
        end
        
        function results = UpdateUITable(app)
            app.UITable.Data =[];
            for i=1:length(app.blockchain.chain)
     s={string(app.blockchain.chain(i).timestamp),num2str(app.blockchain.chain(i).data),num2str(app.blockchain.chain(i).nonce),app.blockchain.chain(i).hash,app.blockchain.chain(i).previous_hash,num2str(app.blockchain.chain(i).difficulty)};
     app.UITable.Data =[app.UITable.Data;cellstr(s)];
    
end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)


            app.blockchain = Blockchain(double(app.MinerateEditField.Value));
            
             
            app.opts = weboptions('MediaType','application/json');
            %app.peerlistendpoint ="https://sheet.best/api/sheets/9cabe1c0-7f05-4256-baff-6fcafc40c602/Sheet1";
            
            
           app.remotepeerendpoint = "https://api.steinhq.com/v1/storages/60c99be5d2a8585c5af2824b/Sheet1";
            
            genisisblock=app.blockchain.chain(1);
            genisisblockstruct = struct(genisisblock);
            webwrite(app.remotepeerendpoint,{genisisblockstruct},app.opts);
            
            
            %start Timed Updataion of Peers
            
            app.chainupdatetimer = timer("ExecutionMode",'fixedRate','Period',30,'TimerFcn',{@UI_Blockchain.updateChain,app});
            start(app.chainupdatetimer);
            app.UpdateUITable();

            
            %start Timed updation of peers
      
             app.peerupdatetimer = timer("ExecutionMode",'fixedRate','Period',120,'TimerFcn',{@UI_Blockchain.updatePeers,app});
             start(app.peerupdatetimer);


        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
             app.peerupdatetimer
            stop(app.peerupdatetimer);
            delete(app.peerupdatetimer);
            delete(timerfind);
            clear peerupdatetimer;
        end

        % Button pushed function: MineButton
        function MineButtonPushed(app, event)
            app.blockchain = app.blockchain.add_block(app.DataEditField.Value);
            app.UpdateUITable();
            app.write2peers(app.blockchain.chain(end));


        end

        % Button pushed function: AddremoteEndpointButton
        function AddremoteEndpointButtonPushed(app, event)
            app.remotepeerendpoint = app.EditField_2.Value ;
        end

        % Button pushed function: JoinNetworkButton
        function JoinNetworkButtonPushed(app, event)
            app.peerlistendpoint =  app.EditField_3.Value ;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BlockChainTab = uitab(app.TabGroup);
            app.BlockChainTab.Title = 'BlockChain';
            app.BlockChainSimulationAnirudhsNodeLabel = uilabel(app.BlockChainTab);
            app.BlockChainSimulationAnirudhsNodeLabel.Text = '      BlockChain Simulation - Anirudh''s Node ';
            app.DataEditFieldLabel = uilabel(app.BlockChainTab);
            app.DataEditFieldLabel.Text = 'Data';
            app.DataEditField = uieditfield(app.BlockChainTab, 'text');
            app.MineButton = uibutton(app.BlockChainTab, 'push');
            app.MineButton.ButtonPushedFcn = createCallbackFcn(app, @MineButtonPushed, true);
            app.MineButton.Text = 'Mine';
            app.UITable = uitable(app.BlockChainTab);
            app.UITable.ColumnName = {'timestamp'; 'data'; 'nonce'; 'hash'; 'previous_hash'; 'difficulty'};
            app.UITable.RowName = {};
            app.MinerateEditField = uieditfield(app.BlockChainTab, 'text');
            app.MinerateEditField.Value = '1';
            app.StartButton = uibutton(app.BlockChainTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.CENLabel = uilabel(app.BlockChainTab);
            app.CENLabel.Text = '©CEN';
            app.StopButton = uibutton(app.BlockChainTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.DataEditFieldLabel_2 = uilabel(app.BlockChainTab);
            app.DataEditFieldLabel_2.Text = 'Minerate';
            app.PeersTab = uitab(app.TabGroup);
            app.PeersTab.Title = 'Peers';
            app.EditField_2 = uieditfield(app.PeersTab, 'text');
            app.AddremoteEndpointButton = uibutton(app.PeersTab, 'push');
            app.AddremoteEndpointButton.ButtonPushedFcn = createCallbackFcn(app, @AddremoteEndpointButtonPushed, true);
            app.AddremoteEndpointButton.Text = 'Add remote Endpoint';
            app.CENLabel_2 = uilabel(app.PeersTab);
            app.CENLabel_2.Text = '©CEN';
            app.EditField_3 = uieditfield(app.PeersTab, 'text');
            app.JoinNetworkButton = uibutton(app.PeersTab, 'push');
            app.JoinNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @JoinNetworkButtonPushed, true);
            app.JoinNetworkButton.Text = 'Join Network';
            app.AIE24Label = uilabel(app.UIFigure);
            app.AIE24Label.Text = 'AIE''24';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI_Blockchain

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end